classdef robot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RobotSwitchPanel        matlab.ui.container.Panel
        RobotEnableSwitch       matlab.ui.control.Switch
        ResetFaultButton        matlab.ui.control.StateButton
        RobotStatus             matlab.ui.control.Label
        ConnectButton           matlab.ui.control.Button
        ADSConnectionLabel      matlab.ui.control.Label
        ConnectTimer            matlab.ui.control.Label
        RobotStatePanel         matlab.ui.container.Panel
        RobotRotation           matlab.ui.control.UIAxes
        RobotDisplacement       matlab.ui.control.UIAxes
        RobotOperationsPanel    matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        PPTab                   matlab.ui.container.Tab
        distanceEditFieldLabel  matlab.ui.control.Label
        distanceEditField       matlab.ui.control.EditField
        angleEditFieldLabel     matlab.ui.control.Label
        angleEditField          matlab.ui.control.EditField
        PPRunButton             matlab.ui.control.Button
        CSPTab                  matlab.ui.container.Tab
        HMTab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        port_opened = false; % Description
        countdown_timer;       % For ADS timer countdown
        countdown_time = 1800; % Seconds. Trial version of ADS 
                               % for Matlab only allows 30 min run.
        synchronize_timer;     % For update the robot status.
        robot_settings;
        motor_settings;
    end
    
    
    methods (Access = public)
        function ADSTimerCallback(app,~,~)
            app.countdown_time = app.countdown_time - 1;
            app.ConnectTimer.Text = num2str(app.countdown_time);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.countdown_time > 0
                [app.robot_settings, app.motor_settings]=matlabIO();
            end
            if app.port_opened == false
                app.port_opened = true;
                app.countdown_timer = timer('ExecutionMode', 'FixedRate', ...
                'Period', 1);
                app.countdown_timer.TimerFcn = @app.ADSTimerCallback;
                start(app.countdown_timer);
            end
        end

        % Value changed function: RobotEnableSwitch
        function RobotEnableSwitchValueChanged(app, event)
            if app.port_opened
                
                app.robot_settings.robot_interface.robot_enable.Write("On" == app.RobotEnableSwitch.Value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotSwitchPanel = uipanel(app.UIFigure);
            app.RobotSwitchPanel.Title = 'Robot Switch';
            app.RobotEnableSwitch = uiswitch(app.RobotSwitchPanel, 'slider');
            app.RobotEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @RobotEnableSwitchValueChanged, true);
            app.ResetFaultButton = uibutton(app.RobotSwitchPanel, 'state');
            app.ResetFaultButton.Text = 'Reset Fault';
            app.RobotStatus = uilabel(app.RobotSwitchPanel);
            app.RobotStatus.Text = 'Robot Power Off';
            app.ConnectButton = uibutton(app.RobotSwitchPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ADSConnectionLabel = uilabel(app.RobotSwitchPanel);
            app.ADSConnectionLabel.Text = 'ADS Connection';
            app.ConnectTimer = uilabel(app.RobotSwitchPanel);
            app.ConnectTimer.Text = '00:00';
            app.RobotStatePanel = uipanel(app.UIFigure);
            app.RobotStatePanel.Title = 'Robot State';
            app.RobotRotation = uiaxes(app.RobotStatePanel);
            title(app.RobotRotation, 'Robot Rotation')
            xlabel(app.RobotRotation, 'X')
            ylabel(app.RobotRotation, 'Y')
            zlabel(app.RobotRotation, 'Z')
            app.RobotDisplacement = uiaxes(app.RobotStatePanel);
            title(app.RobotDisplacement, 'Robot Displacement')
            xlabel(app.RobotDisplacement, 'X')
            ylabel(app.RobotDisplacement, 'Y')
            zlabel(app.RobotDisplacement, 'Z')
            app.RobotOperationsPanel = uipanel(app.UIFigure);
            app.RobotOperationsPanel.Title = 'Robot Operations';
            app.TabGroup = uitabgroup(app.RobotOperationsPanel);
            app.PPTab = uitab(app.TabGroup);
            app.PPTab.Title = 'PP';
            app.distanceEditFieldLabel = uilabel(app.PPTab);
            app.distanceEditFieldLabel.Text = 'distance';
            app.distanceEditField = uieditfield(app.PPTab, 'text');
            app.angleEditFieldLabel = uilabel(app.PPTab);
            app.angleEditFieldLabel.Text = 'angle';
            app.angleEditField = uieditfield(app.PPTab, 'text');
            app.PPRunButton = uibutton(app.PPTab, 'push');
            app.PPRunButton.Text = 'Run';
            app.CSPTab = uitab(app.TabGroup);
            app.CSPTab.Title = 'CSP';
            app.HMTab = uitab(app.TabGroup);
            app.HMTab.Title = 'HM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end